classdef OwObotwithGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OwOBotTextAreaLabel             matlab.ui.control.Label
        OwOBotTextArea                  matlab.ui.control.TextArea
        InsertStringhereEditFieldLabel  matlab.ui.control.Label
        InsertStringhereEditField       matlab.ui.control.EditField
        FinalResultTextAreaLabel        matlab.ui.control.Label
        FinalResultTextArea             matlab.ui.control.TextArea
        Image                           matlab.ui.control.Image
        OwOifyButton                    matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OwOifyButton
        function OwOifyButtonPushed(app, event)
            x = string(app.InsertStringhereEditField.Value);
            start1 = "Haiii!!!"; %to put at the beginning of the owoified string
   start2 = "<3";
start3 = "OWO";
end1 = "(◠‿◠✿)"; %put at the end of the owoified string
end2 = "(✿ ♡‿♡)";
end3 = "(●´ω｀●)";
OwO1 = strrep(x, 'r','w');        %modification steps in owoifying
OwO2 = strrep(OwO1,'you','uu');
OwO3 = strrep(OwO2,'has','haz');
OwO4 = strrep(OwO3,'l','w');
OwO5 = strrep(OwO4,'this','dis');
OwO_Complete = OwO5;
r1 = randi(3);                %random number to select which beginning
r2 = randi(3);                %random number to select which end

if r1 == 1                        %selection statements for which append to use
    OwO_Complete = append(start1,' ',OwO5);
else 
    if r1 == 2
        OwO_Complete = append(start2,' ',OwO5);
    end
end

if r2 == 3
    OwO_Complete = append(start3,' ',OwO5);
end

if r2 == 1
    OwO_Complete = append(OwO_Complete,' ',end1);
else 
    if r2 == 2
        OwO_Complete = append(OwO_Complete,' ',end2);
    end
    if r2 == 3
            OwO_Complete = append(OwO_Complete,' ',end3);
    end
end
OwO_Complete = strrep(OwO_Complete,'R','W'); %final changes
OwO_Complete = strrep(OwO_Complete,'L','W');
app.FinalResultTextArea.Value = OwO_Complete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OwOBotTextAreaLabel = uilabel(app.UIFigure);
            app.OwOBotTextAreaLabel.Text = 'OwO Bot';
            app.OwOBotTextArea = uitextarea(app.UIFigure);
            app.OwOBotTextArea.Value = {'insert a string to OwOify below'};
            app.InsertStringhereEditFieldLabel = uilabel(app.UIFigure);
            app.InsertStringhereEditFieldLabel.Text = 'Insert String here';
            app.InsertStringhereEditField = uieditfield(app.UIFigure, 'text');
            app.FinalResultTextAreaLabel = uilabel(app.UIFigure);
            app.FinalResultTextAreaLabel.Text = 'Final Result';
            app.FinalResultTextArea = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'owo_5f70236d73cff.png';
            app.OwOifyButton = uibutton(app.UIFigure, 'push');
            app.OwOifyButton.ButtonPushedFcn = createCallbackFcn(app, @OwOifyButtonPushed, true);
            app.OwOifyButton.Text = 'OwOify';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OwObotwithGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end